classdef stabilization_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        MainGrid                  matlab.ui.container.GridLayout
        FrameSlider               matlab.ui.control.Slider
        ControlPanel              matlab.ui.container.Panel
        Label_5                   matlab.ui.control.Label
        Label_4                   matlab.ui.control.Label
        Label_3                   matlab.ui.control.Label
        Label_2                   matlab.ui.control.Label
        Label                     matlab.ui.control.Label
        DeblurSwitch              matlab.ui.control.Switch
        DeblurSwitchLabel         matlab.ui.control.Label
        WindowRadiusSpinner       matlab.ui.control.Spinner
        WindowRadiusSpinnerLabel  matlab.ui.control.Label
        SmoothDropDown            matlab.ui.control.DropDown
        SmoothDropDownLabel       matlab.ui.control.Label
        FlowDropDown              matlab.ui.control.DropDown
        FlowDropDownLabel         matlab.ui.control.Label
        DetectorDropDown          matlab.ui.control.DropDown
        DetectorDropDownLabel     matlab.ui.control.Label
        TrajectoryBtn             matlab.ui.control.Button
        ExportBtn                 matlab.ui.control.Button
        PauseBtn                  matlab.ui.control.Button
        StartBtn                  matlab.ui.control.Button
        LoadBtn                   matlab.ui.control.Button
        FrameInfoLabel            matlab.ui.control.Label
        RightAxes                 matlab.ui.control.UIAxes
        LeftAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
            % --- 视频状态 ---
        videoPath char = ''
        N_total double = 0
        currentFrameIdx double = 1

        % --- 处理控制 ---
        isProcessing logical = false
        isPaused logical = false
        phase1Done logical = false
        phase2Done logical = false
        phase3Done logical = false

        % --- 变换矩阵 ---
        T_raw_seq double = []
        T_smoothed_seq double = []

        % --- 输出 ---
        outputPath char = ''

        % --- 并行 ---
        hasParallelPool logical = false

    end
    
    methods (Access = public)
        
% --- 方法 1: onFrameUpdateCB（接收 Worker 推送的帧更新）---
        function onFrameUpdateCB(app, data)
            idx = data.idx;
            N = data.N;
            pct = round(idx / N * 100);

            if data.phase == 1
                imshow(data.frame, 'Parent', app.LeftAxes);
                app.LeftAxes.Title.String = sprintf('阶段一 运动估计 %d/%d (%d%%)', idx, N, pct);
                if isfield(data, 'inlierRatio') && ~isnan(data.inlierRatio)
                    app.RightAxes.Title.String = sprintf('内点率: %.2f', data.inlierRatio);
                end
            else
                imshow(data.frame, 'Parent', app.RightAxes);
                infoParts = {sprintf('稳定帧 %d/%d', idx, N)};
                if isfield(data, 'psnr') && ~isnan(data.psnr)
                    infoParts{end+1} = sprintf('PSNR: %.1f dB', data.psnr);
                end
                if isfield(data, 'ssim') && ~isnan(data.ssim)
                    infoParts{end+1} = sprintf('SSIM: %.3f', data.ssim);
                end
                if isfield(data, 'deblurApplied') && data.deblurApplied
                    infoParts{end+1} = '去模糊: 开';
                end
                app.RightAxes.Title.String = strjoin(infoParts, ' | ');
            end

            app.currentFrameIdx = idx;
            app.FrameInfoLabel.Text = sprintf('帧: %d / %d | 进度: %d%%', idx, N, pct);
            app.FrameSlider.Value = idx;
            drawnow limitrate;
        end
% --- 方法 2: onDiagUpdateCB（接收 Worker 推送的诊断事件）---
        function onDiagUpdateCB(app, data)
            switch data.type
                case 'phase'
                    if strcmp(data.status, 'done')
                        switch data.phase
                            case 1
                                app.phase1Done = true;
                                app.LeftAxes.Title.String = '阶段二: 运动分解与平滑...';
                            case 2
                                app.phase2Done = true;
                                app.TrajectoryBtn.Enable = 'on';
                                app.LeftAxes.Title.String = sprintf(...
                                    '阶段三: 帧合成与去模糊... | 策略: %s', data.strategy);
                            case 3
                                app.phase3Done = true;
                        end
                    end
                case 'trajectory'
                    app.T_raw_seq = data.T_raw_seq;
                    app.T_smoothed_seq = data.T_smoothed_seq;
                case 'done'
                    onProcessingComplete(app);
            end
        end

% --- 方法 3: onProcessingComplete ---
        function onProcessingComplete(app)
            app.isProcessing = false;
            app.isPaused = false;
            app.StartBtn.Enable = 'on';
            app.LoadBtn.Enable = 'on';
            app.PauseBtn.Enable = 'off';
            app.ExportBtn.Enable = 'on';
            app.TrajectoryBtn.Enable = 'on';
            app.PauseBtn.Text = '暂停';
            app.LeftAxes.Title.String = '原始视频预览';
            app.RightAxes.Title.String = '稳定结果';
            app.FrameInfoLabel.Text = sprintf('完成 | %d 帧 | 输出: stabilized_output.mp4', app.N_total);
        end

    end
    
    methods (Access = private)
        
% --- 方法 1: buildMotionParams ---
        function p = buildMotionParams(app)
            p = struct();
            p.detect_params = struct();
            if strcmp(app.DetectorDropDown.Value, 'SIFT+SURF')
                p.detect_params.use_dual_detector = true;
            else
                p.detect_params.use_dual_detector = false;
            end
            p.flow_params = struct('method', app.FlowDropDown.Value);
            p.use_sparse_fusion = true;
        end

% --- 方法 2: buildSmoothParams ---
        function p = buildSmoothParams(app)
            p = struct();
            p.smooth_strategy = app.SmoothDropDown.Value;
            p.smooth_params = struct('window_radius', app.WindowRadiusSpinner.Value);
            p.online_mode = false;
        end

% --- 方法 3: buildSynthParams ---
        function p = buildSynthParams(app)
            p = struct();
            p.enable_deblur = logical(app.DeblurSwitch.Value);
            p.deblur_threshold = 0.05;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % 添加 src/ 全部子目录到 MATLAB 路径
            appDir = fileparts(mfilename('fullpath'));
            srcDir = fullfile(appDir, '..');
            addpath(fullfile(srcDir, 'motion_estimation'));
            addpath(fullfile(srcDir, 'motion_smoothing'));
            addpath(fullfile(srcDir, 'frame_synthesis'));
            addpath(fullfile(srcDir, 'evaluation'));
            addpath(fullfile(srcDir, 'utils'));
            addpath(appDir);

            % 尝试启动 Parallel Pool
            if ~isempty(ver('parallel'))
                try
                    pool = gcp('nocreate');
                    if isempty(pool)
                        parpool('local', 1);
                    end
                    app.hasParallelPool = true;
                catch
                    app.hasParallelPool = false;
                end
            end

            app.FrameInfoLabel.Text = '状态: 就绪 | 请加载视频';
        end

        % Button pushed function: LoadBtn
        function LoadBtnPushed(app, event)
            [file, folder] = uigetfile({'*.mp4;*.avi;*.mov;*.mj2', ...
                '视频文件 (*.mp4, *.avi, *.mov, *.mj2)'}, '选择测试视频');
            if isequal(file, 0)
                return;
            end

            app.videoPath = fullfile(folder, file);
            v = VideoReader(app.videoPath);
            app.N_total = v.NumFrames;
            app.currentFrameIdx = 1;

            app.FrameSlider.Limits = [1, app.N_total];
            app.FrameSlider.Value = 1;

            firstFrame = read(v, 1);
            imshow(firstFrame, 'Parent', app.LeftAxes);
            app.LeftAxes.Title.String = '原始视频预览 - 帧 1';

            app.FrameInfoLabel.Text = sprintf('帧: 1 / %d', app.N_total);
            app.StartBtn.Enable = 'on';
            app.FrameInfoLabel.Text = sprintf('已加载 | %dx%d, %d帧, %.0ffps', ...
                v.Width, v.Height, app.N_total, v.FrameRate);

        end

        % Button pushed function: PauseBtn
        function PauseBtnPushed(app, event)
            if ~app.isProcessing
                return;
            end

            if app.isPaused
                if ~isempty(app.pauseQueue)
                    send(app.pauseQueue, 'resume');
                end
                app.isPaused = false;
                app.PauseBtn.Text = '暂停';
                app.LeftAxes.Title.String = '处理中...';
            else
                if ~isempty(app.pauseQueue)
                    send(app.pauseQueue, 'pause');
                end
                app.isPaused = true;
                app.PauseBtn.Text = '继续';
                app.LeftAxes.Title.String = '已暂停';
            end

        end

        % Button pushed function: StartBtn
        function StartBtnPushed(app, event)
            if app.isProcessing && ~app.isPaused
                return;
            end

            app.isProcessing = true;
            app.isPaused = false;
            app.phase1Done = false;
            app.phase2Done = false;
            app.phase3Done = false;

            app.StartBtn.Enable = 'off';
            app.LoadBtn.Enable = 'off';
            app.PauseBtn.Enable = 'on';
            app.ExportBtn.Enable = 'off';
            app.TrajectoryBtn.Enable = 'off';
            app.LeftAxes.Title.String = '阶段一: 运动估计扫描中...';

            motionParams = buildMotionParams(app);
            smoothParams = buildSmoothParams(app);
            synthParams = buildSynthParams(app);

            outputDir = fullfile(fileparts(mfilename('fullpath')), '..', '..', 'data', 'results');
            if ~exist(outputDir, 'dir')
                mkdir(outputDir);
            end
            app.outputPath = fullfile(outputDir, 'stabilized_output.mp4');

            if app.hasParallelPool
                app.frameQueue = parallel.pool.DataQueue;
                afterEach(app.frameQueue, @app.onFrameUpdateCB);

                app.pauseQueue = parallel.pool.PollableDataQueue;
                app.diagQueue = parallel.pool.DataQueue;
                afterEach(app.diagQueue, @app.onDiagUpdateCB);

                app.futures = parfeval(@phase_worker, 0, ...
                    app.videoPath, motionParams, smoothParams, synthParams, ...
                    app.frameQueue, app.pauseQueue, app.diagQueue, ...
                    1, app.N_total, app.outputPath);
            else
                uialert(app.UIFigure, ...
                    'Parallel Computing Toolbox 不可用，将使用同步模式。', ...
                    '同步模式', 'Icon', 'warning');
                phase_worker(app.videoPath, motionParams, smoothParams, synthParams, ...
                    [], [], [], 1, app.N_total, app.outputPath);
                onProcessingComplete(app);
            end

        end

        % Button pushed function: ExportBtn
        function ExportBtnPushed(app, event)
            if isempty(app.outputPath) || ~exist(app.outputPath, 'file')
                uialert(app.UIFigure, '尚未生成输出视频，请先完成处理。', '提示', 'Icon', 'info');
                return;
            end
            [file, folder] = uiputfile({'*.mp4', 'MP4 视频 (*.mp4)'; ...
                '*.avi', 'AVI 视频 (*.avi)'}, '导出稳定视频');
            if isequal(file, 0)
                return;
            end
            exportPath = fullfile(folder, file);
            try
                copyfile(app.outputPath, exportPath, 'f');
                app.FrameInfoLabel.Text = sprintf('已导出: %s', file);
            catch ME
                uialert(app.UIFigure, sprintf('导出失败: %s', ME.message), '错误', 'Icon', 'error');
            end

        end

        % Button pushed function: TrajectoryBtn
        function TrajectoryBtnPushed(app, event)
            if isempty(app.T_raw_seq) || isempty(app.T_smoothed_seq)
                uialert(app.UIFigure, '尚未完成运动平滑，无法显示轨迹。', '提示', 'Icon', 'info');
                return;
            end
            trajectory_viewer(app.T_raw_seq, app.T_smoothed_seq, ...
                struct('parent', app.UIFigure));
        end

        % Value changed function: DetectorDropDown
        function DetectorDropDownValueChanged(app, event)
            value = app.DetectorDropDown.Value;
            
        end

        % Value changed function: FlowDropDown
        function FlowDropDownValueChanged(app, event)
            value = app.FlowDropDown.Value;
            
        end

        % Value changed function: SmoothDropDown
        function SmoothDropDownValueChanged(app, event)
            value = app.SmoothDropDown.Value;
            
        end

        % Value changed function: WindowRadiusSpinner
        function WindowRadiusSpinnerValueChanged(app, event)
            value = app.WindowRadiusSpinner.Value;
            
        end

        % Value changed function: DeblurSwitch
        function DeblurSwitchValueChanged(app, event)
            value = app.DeblurSwitch.Value;
            
        end

        % Value changed function: FrameSlider
        function FrameSliderValueChanged(app, event)
            if ~isempty(app.videoPath) && ~app.isProcessing
                frameIdx = round(app.FrameSlider.Value);
                v = VideoReader(app.videoPath);
                if frameIdx <= v.NumFrames
                    frame = read(v, frameIdx);
                    imshow(frame, 'Parent', app.LeftAxes);
                    app.LeftAxes.Title.String = sprintf('原始视频预览 - 帧 %d', frameIdx);
                    app.FrameInfoLabel.Text = sprintf('帧: %d / %d', frameIdx, app.N_total);
                    app.currentFrameIdx = frameIdx;
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = '视频运动防抖与画面去模糊系统';
            app.MainGrid = uigridlayout(app.UIFigure);
            app.MainGrid.Tag = 'MainGrid';
            app.LeftAxes = uiaxes(app.MainGrid);
            title(app.LeftAxes, '原始视频预览')
            xlabel(app.LeftAxes, 'X')
            ylabel(app.LeftAxes, 'Y')
            zlabel(app.LeftAxes, 'Z')
            app.LeftAxes.Tag = 'LeftAxes';
            app.RightAxes = uiaxes(app.MainGrid);
            title(app.RightAxes, '处理后预览')
            xlabel(app.RightAxes, 'X')
            ylabel(app.RightAxes, 'Y')
            zlabel(app.RightAxes, 'Z')
            app.RightAxes.Tag = 'RightAxes';
            app.FrameInfoLabel = uilabel(app.MainGrid);
            app.FrameInfoLabel.Text = '帧: -- / --';
            app.ControlPanel = uipanel(app.MainGrid);
            app.ControlPanel.Title = 'ControlPanel';
            app.LoadBtn = uibutton(app.ControlPanel, 'push');
            app.LoadBtn.ButtonPushedFcn = createCallbackFcn(app, @LoadBtnPushed, true);
            app.LoadBtn.Tag = 'LoadBtn';
            app.LoadBtn.Text = '加载视频';
            app.StartBtn = uibutton(app.ControlPanel, 'push');
            app.StartBtn.ButtonPushedFcn = createCallbackFcn(app, @StartBtnPushed, true);
            app.StartBtn.Tag = 'StartBtn';
            app.StartBtn.Enable = 'off';
            app.StartBtn.Text = '开始处理';
            app.PauseBtn = uibutton(app.ControlPanel, 'push');
            app.PauseBtn.ButtonPushedFcn = createCallbackFcn(app, @PauseBtnPushed, true);
            app.PauseBtn.Enable = 'off';
            app.PauseBtn.Text = '暂停';
            app.ExportBtn = uibutton(app.ControlPanel, 'push');
            app.ExportBtn.ButtonPushedFcn = createCallbackFcn(app, @ExportBtnPushed, true);
            app.ExportBtn.Enable = 'off';
            app.ExportBtn.Text = '导出结果';
            app.TrajectoryBtn = uibutton(app.ControlPanel, 'push');
            app.TrajectoryBtn.ButtonPushedFcn = createCallbackFcn(app, @TrajectoryBtnPushed, true);
            app.TrajectoryBtn.Enable = 'off';
            app.TrajectoryBtn.Text = '轨迹分析';
            app.DetectorDropDownLabel = uilabel(app.ControlPanel);
            app.DetectorDropDownLabel.Visible = 'off';
            app.DetectorDropDownLabel.Text = 'DetectorDropDown';
            app.DetectorDropDown = uidropdown(app.ControlPanel);
            app.DetectorDropDown.Items = {'SIFT', 'SIFT+SURF'};
            app.DetectorDropDown.ValueChangedFcn = createCallbackFcn(app, @DetectorDropDownValueChanged, true);
            app.DetectorDropDown.Value = 'SIFT';
            app.FlowDropDownLabel = uilabel(app.ControlPanel);
            app.FlowDropDownLabel.Visible = 'off';
            app.FlowDropDownLabel.Text = 'FlowDropDown';
            app.FlowDropDown = uidropdown(app.ControlPanel);
            app.FlowDropDown.Items = {'Farneback', 'HS', 'LK'};
            app.FlowDropDown.ValueChangedFcn = createCallbackFcn(app, @FlowDropDownValueChanged, true);
            app.FlowDropDown.Value = 'Farneback';
            app.SmoothDropDownLabel = uilabel(app.ControlPanel);
            app.SmoothDropDownLabel.Visible = 'off';
            app.SmoothDropDownLabel.Text = 'SmoothDropDown';
            app.SmoothDropDown = uidropdown(app.ControlPanel);
            app.SmoothDropDown.Items = {'markov', 'gaussian_relative', 'gaussian_absolute'};
            app.SmoothDropDown.ValueChangedFcn = createCallbackFcn(app, @SmoothDropDownValueChanged, true);
            app.SmoothDropDown.Value = 'markov';
            app.WindowRadiusSpinnerLabel = uilabel(app.ControlPanel);
            app.WindowRadiusSpinnerLabel.Visible = 'off';
            app.WindowRadiusSpinnerLabel.Text = 'WindowRadiusSpinner';
            app.WindowRadiusSpinner = uispinner(app.ControlPanel);
            app.WindowRadiusSpinner.Limits = [1 5];
            app.WindowRadiusSpinner.ValueChangedFcn = createCallbackFcn(app, @WindowRadiusSpinnerValueChanged, true);
            app.WindowRadiusSpinner.Value = 3;
            app.DeblurSwitchLabel = uilabel(app.ControlPanel);
            app.DeblurSwitchLabel.Visible = 'off';
            app.DeblurSwitchLabel.Text = 'DeblurSwitch';
            app.DeblurSwitch = uiswitch(app.ControlPanel, 'slider');
            app.DeblurSwitch.ValueChangedFcn = createCallbackFcn(app, @DeblurSwitchValueChanged, true);
            app.DeblurSwitch.Value = 'On';
            app.Label = uilabel(app.ControlPanel);
            app.Label.Text = '检测器:';
            app.Label_2 = uilabel(app.ControlPanel);
            app.Label_2.Text = '光流方法:';
            app.Label_3 = uilabel(app.ControlPanel);
            app.Label_3.Text = '平滑策略:';
            app.Label_4 = uilabel(app.ControlPanel);
            app.Label_4.Text = '窗口半径:';
            app.Label_5 = uilabel(app.ControlPanel);
            app.Label_5.Text = '去模糊';
            app.FrameSlider = uislider(app.MainGrid);
            app.FrameSlider.ValueChangedFcn = createCallbackFcn(app, @FrameSliderValueChanged, true);
            app.FrameSlider.Tag = 'FrameSlider';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = stabilization_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end